classdef sweepSR830GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SR830ReadEditField       matlab.ui.control.EditField
        SR830ReadEditFieldLabel  matlab.ui.control.Label
        SweepButton              matlab.ui.control.Button
        DeltaValEditField        matlab.ui.control.NumericEditField
        DeltaValEditFieldLabel   matlab.ui.control.Label
        StopValEditField         matlab.ui.control.NumericEditField
        StopValEditFieldLabel    matlab.ui.control.Label
        StartValEditField        matlab.ui.control.NumericEditField
        StartValEditFieldLabel   matlab.ui.control.Label
        SweepTypeEditField       matlab.ui.control.EditField
        SweepTypeEditFieldLabel  matlab.ui.control.Label
        doBackAndForthCheckBox   matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SweepButton
        function SweepButtonPushed(app, event)
            cleanupObj = onCleanup(@()cleanMeUp(handle));
            sweepMeasSR830_Script;
            
            function cleanMeUp(handle)
                disp('Operation Terminated, saving data');
                saveData(handle,'tVsTime');
            end
        end

        % Value changed function: doBackAndForthCheckBox
        function doBackAndForthCheckBoxValueChanged(app, event)
            value = app.doBackAndForthCheckBox.Value;
            assignin("base","doBackAndForth",value);
        end

        % Value changed function: StartValEditField
        function StartValEditFieldValueChanged(app, event)
            value = app.StartValEditField.Value;
            assignin("base","startV",value);
        end

        % Value changed function: StopValEditField
        function StopValEditFieldValueChanged(app, event)
            value = app.StopValEditField.Value;
            assignin("base","stopV",value);
        end

        % Value changed function: DeltaValEditField
        function DeltaValEditFieldValueChanged(app, event)
            value = app.DeltaValEditField.Value;
            assignin("base","deltaV",value);
        end

        % Value changed function: SweepTypeEditField
        function SweepTypeEditFieldValueChanged(app, event)
            value = app.SweepTypeEditField.Value;
            assignin("base","sweepType",value);
        end

        % Value changed function: SR830ReadEditField
        function SR830ReadEditFieldValueChanged(app, event)
            value = app.SR830ReadEditField.Value;
            assignin("base","SR830ReadName",value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.doBackAndForthCheckBox = uicheckbox(app.UIFigure);
            app.doBackAndForthCheckBox.ValueChangedFcn = createCallbackFcn(app, @doBackAndForthCheckBoxValueChanged, true);
            app.doBackAndForthCheckBox.Text = 'doBackAndForth?';
            app.SweepTypeEditFieldLabel = uilabel(app.UIFigure);
            app.SweepTypeEditFieldLabel.Text = 'Sweep Type';
            app.SweepTypeEditField = uieditfield(app.UIFigure, 'text');
            app.SweepTypeEditField.ValueChangedFcn = createCallbackFcn(app, @SweepTypeEditFieldValueChanged, true);
            app.StartValEditFieldLabel = uilabel(app.UIFigure);
            app.StartValEditFieldLabel.Text = 'StartVal';
            app.StartValEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartValEditField.ValueChangedFcn = createCallbackFcn(app, @StartValEditFieldValueChanged, true);
            app.StopValEditFieldLabel = uilabel(app.UIFigure);
            app.StopValEditFieldLabel.Text = 'StopVal';
            app.StopValEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopValEditField.ValueChangedFcn = createCallbackFcn(app, @StopValEditFieldValueChanged, true);
            app.DeltaValEditFieldLabel = uilabel(app.UIFigure);
            app.DeltaValEditFieldLabel.Text = 'DeltaVal';
            app.DeltaValEditField = uieditfield(app.UIFigure, 'numeric');
            app.DeltaValEditField.ValueChangedFcn = createCallbackFcn(app, @DeltaValEditFieldValueChanged, true);
            app.SweepButton = uibutton(app.UIFigure, 'push');
            app.SweepButton.ButtonPushedFcn = createCallbackFcn(app, @SweepButtonPushed, true);
            app.SweepButton.Text = 'Sweep';
            app.SR830ReadEditFieldLabel = uilabel(app.UIFigure);
            app.SR830ReadEditFieldLabel.Text = 'SR830 Read';
            app.SR830ReadEditField = uieditfield(app.UIFigure, 'text');
            app.SR830ReadEditField.ValueChangedFcn = createCallbackFcn(app, @SR830ReadEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sweepSR830GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end